classdef Robot_arm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        simulationSwitch       matlab.ui.control.Switch
        simulationSwitchLabel  matlab.ui.control.Label
        length2Slider          matlab.ui.control.Slider
        length2SliderLabel     matlab.ui.control.Label
        length1Slider          matlab.ui.control.Slider
        length1SliderLabel     matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
hold(app.UIAxes,"off")
            Arm1_Length = app.length1Slider.Value; 
            Arm2_Length = app.length2Slider.Value; 
            
            theta1 = linspace(0,90,1000);
            theta2 = linspace(0,180,1000);

            x1 = Arm1_Length * cosd(theta1);
            x2 = x1 + Arm2_Length * cosd(theta1+theta2);
            y1 = Arm1_Length * sind(theta1);
            y2 = y1 + Arm2_Length * sind(theta1 + theta2);

            p1 = plot(app.UIAxes,[0 x1],[0 y1],'LineWidth',2,'Color','r','Marker','*'); 
            hold(app.UIAxes,"on")
            p2 = plot(app.UIAxes,[x1 x2],[y1 y2],'LineWidth',2,'Color','b','Marker','*');

            xlim(app.UIAxes,[-Arm1_Length*2,Arm1_Length*2]);
            ylim(app.UIAxes,[-Arm1_Length*2,Arm1_Length*2]);

            for i = 1:4:length(theta1)
                delete(p1); 
                delete(p2);
                p1 = plot(app.UIAxes,[0 x1(i)],[0 y1(i)],'LineWidth',2,'Color','r','Marker','*');
                p2 = plot(app.UIAxes,[x1(i) x2(i)],[y1(i) y2(i)],'LineWidth',2,'Color','b','Marker','*');
               drawnow
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot-Arm')
            xlabel(app.UIAxes, 'X-Axis')
            ylabel(app.UIAxes, 'Y-Axis')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.length1SliderLabel = uilabel(app.UIFigure);
            app.length1SliderLabel.Text = 'length(1)';
            app.length1Slider = uislider(app.UIFigure);
            app.length1Slider.Limits = [0 20];
            app.length1Slider.Value = 8;
            app.length2SliderLabel = uilabel(app.UIFigure);
            app.length2SliderLabel.Text = 'length(2)';
            app.length2Slider = uislider(app.UIFigure);
            app.length2Slider.Limits = [0 20];
            app.length2Slider.Value = 7;
            app.simulationSwitchLabel = uilabel(app.UIFigure);
            app.simulationSwitchLabel.Text = 'simulation';
            app.simulationSwitch = uiswitch(app.UIFigure, 'slider');
            app.simulationSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_arm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end